classdef The_Sotware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompositeImageGeneratorUIFigure  matlab.ui.Figure
        SelectManmadeImagesButton  matlab.ui.control.Button
        GenerateImageButton        matlab.ui.control.Button
        SelectTargetImageButton    matlab.ui.control.Button
        PictureFrame               matlab.ui.control.UIAxes
        WidthLabel                 matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        OutputResolutionLabel      matlab.ui.control.Label
        HeightEditFieldLabel       matlab.ui.control.Label
        HeightEditField            matlab.ui.control.NumericEditField
        TilesLabel                 matlab.ui.control.Label
        HorizontalEditFieldLabel   matlab.ui.control.Label
        HorizontalTilesField       matlab.ui.control.NumericEditField
        VerticalEditFieldLabel     matlab.ui.control.Label
        VerticalTilesField         matlab.ui.control.NumericEditField
        ErrorMessage               matlab.ui.control.Label
        SelectNaturalImagesButton  matlab.ui.control.Button
        ClassifyImagesCheckBox     matlab.ui.control.CheckBox
        LoadModelButton            matlab.ui.control.Button
        TrainClassifierButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        imgSetPath; % stores path to folder
        baseImgPath; % path to image to be mosaiced
        
        targetImage;
        targetImageLoaded = false;
        
        pixelWidth = 0; % width of the output image
        pixelHeight = 0; % height of the output image
        horTiles = 0; % number of horizontal tiles
        vertTiles = 0 % number of vertical tiles
        
        manmadeImageSet % set of images to be used as tiles
        manmadeImageSetLoaded = false; % stores whether or not a image set has been loaded
        
        naturalImageSet % set of images to be used as tiles
        naturalImageSetLoaded = false; % stores whether or not a image set has been loaded
        
        canGenerate = false; % whether or not the resolution is positive and divisible by the number of tiles
        canClassify = false; % whether or not to attempt to classify the images
        classifyCheckbox = false; % whether or not the user has chosen to classify the images
        classificationModel; % model trained in part b (loaded in by file)
    end
    
    methods (Access = public)
        
        function ValidateInputs(app)
            if app.pixelHeight <= 0 || app.pixelWidth <= 0|| app.horTiles <= 0 || app.vertTiles <= 0
                app.canGenerate = false;
            elseif (mod(app.pixelHeight, app.vertTiles) || mod(app.pixelWidth, app.horTiles))
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('The resolution is not divisible by the \nnumber of tiles');
            elseif (app.targetImageLoaded == false)
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load a target image');
            elseif (app.manmadeImageSetLoaded == false)
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the manmade image set');
            elseif (app.naturalImageSetLoaded == false)
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the natural image set');
            elseif (app.classifyCheckbox == true && isempty(app.classificationModel))
                    app.canClassify = false;
                    app.ErrorMessage.FontColor = [1 0 0];
                    app.ErrorMessage.Text = sprintf('Please load classification model');
            else
                app.canGenerate = true;
                app.ErrorMessage.FontColor = [0 0 0];
                app.ErrorMessage.Text = 'Ready to generate mosaic';
            end
            
            if (app.classifyCheckbox == true && isempty(app.classificationModel) == false)
                app.canClassify = true;
            end
        end
    
        function y = TileResolution(app, picRes, tileRes)
            y = picRes / tileRes;
        end
    
        function ts = concatenateImageSet(app)
            ts = cat(2, app.manmadeImageSet, app.naturalImageSet);
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectManmadeImagesButton
        function SelectManmadeImagesButtonPushed(app, event)
            app.manmadeImageSetLoaded = false;
            ValidateInputs(app);
            app.imgSetPath = uigetdir+"\";
            app.manmadeImageSet = filesRead(app.imgSetPath);
            if(isempty(app.manmadeImageSet))
                app.manmadeImageSetLoaded = false;
            else
                app.manmadeImageSetLoaded = true;
            end
            ValidateInputs(app);
        end

        % Button pushed function: GenerateImageButton
        function GenerateImageButtonPushed(app, event)
            if(app.canGenerate == true)
                % Code to generate the mosaic and open it in a new window
                tileX = TileResolution(app, app.pixelWidth, app.horTiles);
                tileY = TileResolution(app, app.pixelHeight, app.vertTiles);
                
                if(app.canClassify == true)
                    % classify target image
                    img = imresize(app.targetImage, [180 180]);
                    i{1} = img;
                    t = getAllFeatures(i);
                    label = predict(app.classificationModel, t);
                    
                    if (label == 1)
                        msgbox('Image classified as manmade');
                        selectedImageSet = app.manmadeImageSet;
                    elseif (label == 0)
                        msgbox('Image classified as natural');
                        selectedImageSet = app.naturalImageSet;
                    else
                        msgbox('Error: Image could not be classified');
                        selectedImageSet = concatenateImageSet(app);
                    end
                else
                    % concatenate image sets
                    selectedImageSet = concatenateImageSet(app);
                end
                
                % Resize target image
                baseImage = imresize(app.targetImage, [app.pixelHeight, app.pixelWidth]);
                
                imageSet = prepare_images(selectedImageSet, tileX, tileY);
                figure('Name','Composite Image','NumberTitle','off'), imshow(pic_built(baseImage, imageSet, tileY, tileX, app.vertTiles, app.horTiles));
            end
        end

        % Button pushed function: SelectTargetImageButton
        function SelectTargetImageButtonPushed(app, event)
            [file, path] = uigetfile;
            figure(app.CompositeImageGeneratorUIFigure);
            app.targetImage = imread(fullfile(path, file));
            imshow(fullfile(path, file), 'Parent', app.PictureFrame);
            app.targetImageLoaded = true;
            ValidateInputs(app);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.pixelHeight = app.HeightEditField.Value;
            ValidateInputs(app);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.pixelWidth = app.WidthEditField.Value;
            ValidateInputs(app)
        end

        % Value changed function: HorizontalTilesField
        function HorizontalTilesFieldValueChanged(app, event)
            app.horTiles = app.HorizontalTilesField.Value;
            ValidateInputs(app)
        end

        % Value changed function: VerticalTilesField
        function VerticalTilesFieldValueChanged(app, event)
            app.vertTiles = app.VerticalTilesField.Value;
            ValidateInputs  (app);
        end

        % Button pushed function: SelectNaturalImagesButton
        function SelectNaturalImagesButtonPushed(app, event)
            app.naturalImageSetLoaded = false;
            ValidateInputs(app);
            app.imgSetPath = uigetdir+"\";
            app.naturalImageSet = filesRead(app.imgSetPath);
            if(isempty(app.naturalImageSet))
                app.naturalImageSetLoaded = false;
            else
                app.naturalImageSetLoaded = true;
            end
            ValidateInputs(app);
        end

        % Value changed function: ClassifyImagesCheckBox
        function ClassifyImagesCheckBoxValueChanged(app, event)
            app.classifyCheckbox = app.ClassifyImagesCheckBox.Value;
            ValidateInputs(app);
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            temp = app.classificationModel;
            [file, path] = uigetfile;
            
            if(file == 0)
                app.classificationModel = temp;
            else
                app.classificationModel = loadCompactModel(fullfile(path, file));
                msgbox('Model loaded successfully');
            end
            
            ValidateInputs(app);
        end

        % Button pushed function: TrainClassifierButton
        function TrainClassifierButtonPushed(app, event)
            train_model_GUI;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompositeImageGeneratorUIFigure = uifigure;
            app.CompositeImageGeneratorUIFigure.Name = 'Composite Image Generator';
            app.SelectManmadeImagesButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.SelectManmadeImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectManmadeImagesButtonPushed, true);
            app.SelectManmadeImagesButton.Text = 'Select Manmade Images';
            app.GenerateImageButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.GenerateImageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateImageButtonPushed, true);
            app.GenerateImageButton.Text = 'Generate Image';
            app.SelectTargetImageButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.SelectTargetImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetImageButtonPushed, true);
            app.SelectTargetImageButton.Text = 'Select Target Image';
            app.PictureFrame = uiaxes(app.CompositeImageGeneratorUIFigure);
            title(app.PictureFrame, '')
            app.PictureFrame.XTick = [];
            app.PictureFrame.YTick = [];
            app.WidthLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.WidthLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CompositeImageGeneratorUIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.OutputResolutionLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.OutputResolutionLabel.Text = 'Output Resolution';
            app.HeightEditFieldLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CompositeImageGeneratorUIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.TilesLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.TilesLabel.Text = 'No. of Tiles';
            app.HorizontalEditFieldLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalTilesField = uieditfield(app.CompositeImageGeneratorUIFigure, 'numeric');
            app.HorizontalTilesField.ValueChangedFcn = createCallbackFcn(app, @HorizontalTilesFieldValueChanged, true);
            app.VerticalEditFieldLabel = uilabel(app.CompositeImageGeneratorUIFigure);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalTilesField = uieditfield(app.CompositeImageGeneratorUIFigure, 'numeric');
            app.VerticalTilesField.ValueChangedFcn = createCallbackFcn(app, @VerticalTilesFieldValueChanged, true);
            app.ErrorMessage = uilabel(app.CompositeImageGeneratorUIFigure);
            app.ErrorMessage.Text = '';
            app.SelectNaturalImagesButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.SelectNaturalImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNaturalImagesButtonPushed, true);
            app.SelectNaturalImagesButton.Text = 'Select Natural Images';
            app.ClassifyImagesCheckBox = uicheckbox(app.CompositeImageGeneratorUIFigure);
            app.ClassifyImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClassifyImagesCheckBoxValueChanged, true);
            app.ClassifyImagesCheckBox.Text = 'Classify Images';
            app.LoadModelButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.TrainClassifierButton = uibutton(app.CompositeImageGeneratorUIFigure, 'push');
            app.TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @TrainClassifierButtonPushed, true);
            app.TrainClassifierButton.Text = 'Train Classifier';
        end
    end

    methods (Access = public)

        % Construct app
        function app = The_Sotware

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompositeImageGeneratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompositeImageGeneratorUIFigure)
        end
    end
end