classdef train_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainModel                    matlab.ui.Figure
        LoadManmadeTrainingSetButton  matlab.ui.control.Button
        LoadNaturalTrainingSetButton  matlab.ui.control.Button
        TrainKNNModelButton           matlab.ui.control.Button
        NumberofNeighboursEditFieldLabel  matlab.ui.control.Label
        NumberofNeighboursEditField   matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel      matlab.ui.control.Label
        ImageWidthEditField           matlab.ui.control.NumericEditField
        ImageHeightEditFieldLabel     matlab.ui.control.Label
        ImageHeightEditField          matlab.ui.control.NumericEditField
        LoadManmadeTestSetButton      matlab.ui.control.Button
        DistanceDropDownLabel         matlab.ui.control.Label
        DistanceDropDown              matlab.ui.control.DropDown
        ErrorMessage                  matlab.ui.control.Label
        LoadNaturalTestSetButton      matlab.ui.control.Button
        PredictImageButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        mTestSet;
        mTestSetLoaded = false;
        
        nTestSet;
        nTestSetLoaded = false;
        
        numNeighbours = 20;
        distance = 'cosine';
        
        imageWidth = 180; % width of the image
        imageHeight = 180; % height of the image
        
        manmadeImageSet % manmade training set
        manmadeImageSetLoaded = false; % stores whether or not a image set has been loaded
        
        naturalImageSet % natural training set
        naturalImageSetLoaded = false; % stores whether or not a image set has been loaded
        
        canTrain = false;
    end
    
    methods (Access = public)
        function ValidateInputs(app)
            if app.numNeighbours <= 0
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('The number of neighbours \nmust be larger than 0');
            elseif app.imageHeight <= 0 || app.imageWidth <= 0
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Image resolution must be \nlarger than 0.\nThe recommended size is 180.');
            elseif (app.manmadeImageSetLoaded == false)
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the manmade \ntraining set');
            elseif (app.naturalImageSetLoaded == false)
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the natural \ntraining set');
            elseif (app.mTestSetLoaded == false)
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the manmade \ntest set');
            elseif (app.nTestSetLoaded == false)
                app.canTrain = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load the natural \ntest set');
            else
                app.canTrain = true;
                app.ErrorMessage.FontColor = [0 0 0];
                app.ErrorMessage.Text = "Ready to train model";
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: LoadManmadeTrainingSetButton
        function LoadManmadeTrainingSetButtonPushed(app, event)
            app.manmadeImageSetLoaded = false;
            ValidateInputs(app);
            imgSetPath = uigetdir+"\";
            app.manmadeImageSet = filesRead(imgSetPath);
            if(isempty(app.manmadeImageSet))
                app.manmadeImageSetLoaded = false;
            else
                app.manmadeImageSetLoaded = true;
            end
            ValidateInputs(app)
        end

        % Button pushed function: LoadNaturalTrainingSetButton
        function LoadNaturalTrainingSetButtonPushed(app, event)
            app.naturalImageSetLoaded = false;
            ValidateInputs(app);
            imgSetPath = uigetdir+"\";
            app.naturalImageSet = filesRead(imgSetPath);
            if(isempty(app.naturalImageSet))
                app.naturalImageSetLoaded = false;
            else
                app.naturalImageSetLoaded = true;
            end
            ValidateInputs(app);
        end

        % Button pushed function: LoadManmadeTestSetButton
        function LoadManmadeTestSetButtonPushed(app, event)
            app.mTestSetLoaded = false;
            ValidateInputs(app);
            imgSetPath = uigetdir+"\";
            app.mTestSet = filesRead(imgSetPath);
            if(isempty(app.mTestSet))
                app.mTestSetLoaded = false;
            else
                app.mTestSetLoaded = true;
            end
            ValidateInputs(app);
        end

        % Value changed function: DistanceDropDown
        function DistanceDropDownValueChanged(app, event)
            app.distance = app.DistanceDropDown.Value;
        end

        % Value changed function: ImageWidthEditField
        function ImageWidthEditFieldValueChanged(app, event)
            app.imageWidth = app.ImageWidthEditField.Value;
            ValidateInputs(app);
        end

        % Value changed function: ImageHeightEditField
        function ImageHeightEditFieldValueChanged(app, event)
            app.imageHeight = app.ImageHeightEditField.Value;
            ValidateInputs(app);
        end

        % Button pushed function: TrainKNNModelButton
        function TrainKNNModelButtonPushed(app, event)
            if (app.canTrain == true)
                % get path to save model to
               [file, path] = uiputfile('.mat');
               savePath = fullfile(path, file);
               
               ml_train(app.imageWidth, app.imageHeight, app.numNeighbours, app.distance, app.manmadeImageSet, app.naturalImageSet, app.mTestSet, app.nTestSet, savePath);
            end
        end

        % Button pushed function: LoadNaturalTestSetButton
        function LoadNaturalTestSetButtonPushed(app, event)
            app.nTestSetLoaded = false;
            ValidateInputs(app);
            imgSetPath = uigetdir+"\";
            app.nTestSet = filesRead(imgSetPath);
            if(isempty(app.nTestSet))
                app.nTestSetLoaded = false;
            else
                app.nTestSetLoaded = true;
            end
            ValidateInputs(app);
        end

        % Value changed function: NumberofNeighboursEditField
        function NumberofNeighboursEditFieldValueChanged(app, event)
            app.numNeighbours = app.NumberofNeighboursEditField.Value;
            ValidateInputs(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainModel = uifigure;
            app.TrainModel.Name = 'Train KNN Model';
            app.LoadManmadeTrainingSetButton = uibutton(app.TrainModel, 'push');
            app.LoadManmadeTrainingSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadManmadeTrainingSetButtonPushed, true);
            app.LoadManmadeTrainingSetButton.Text = 'Load Manmade Training Set';
            app.LoadNaturalTrainingSetButton = uibutton(app.TrainModel, 'push');
            app.LoadNaturalTrainingSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNaturalTrainingSetButtonPushed, true);
            app.LoadNaturalTrainingSetButton.Text = 'Load Natural Training Set';
            app.TrainKNNModelButton = uibutton(app.TrainModel, 'push');
            app.TrainKNNModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainKNNModelButtonPushed, true);
            app.TrainKNNModelButton.Text = 'Train KNN Model';
            app.NumberofNeighboursEditFieldLabel = uilabel(app.TrainModel);
            app.NumberofNeighboursEditFieldLabel.Text = 'Number of Neighbours';
            app.NumberofNeighboursEditField = uieditfield(app.TrainModel, 'numeric');
            app.NumberofNeighboursEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNeighboursEditFieldValueChanged, true);
            app.NumberofNeighboursEditField.Value = 20;
            app.ImageWidthEditFieldLabel = uilabel(app.TrainModel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditField = uieditfield(app.TrainModel, 'numeric');
            app.ImageWidthEditField.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldValueChanged, true);
            app.ImageWidthEditField.Value = 180;
            app.ImageHeightEditFieldLabel = uilabel(app.TrainModel);
            app.ImageHeightEditFieldLabel.Text = 'Image Height';
            app.ImageHeightEditField = uieditfield(app.TrainModel, 'numeric');
            app.ImageHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ImageHeightEditFieldValueChanged, true);
            app.ImageHeightEditField.Value = 180;
            app.LoadManmadeTestSetButton = uibutton(app.TrainModel, 'push');
            app.LoadManmadeTestSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadManmadeTestSetButtonPushed, true);
            app.LoadManmadeTestSetButton.Text = 'Load Manmade Test Set';
            app.DistanceDropDownLabel = uilabel(app.TrainModel);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.TrainModel);
            app.DistanceDropDown.Items = {'cityblock', 'chebychev', 'correlation', 'cosine', 'euclidean', 'hamming', 'jaccard', 'mahalanobis', 'minkowski', 'seuclidean', 'spearman'};
            app.DistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceDropDownValueChanged, true);
            app.DistanceDropDown.Value = 'cosine';
            app.ErrorMessage = uilabel(app.TrainModel);
            app.ErrorMessage.Text = '';
            app.LoadNaturalTestSetButton = uibutton(app.TrainModel, 'push');
            app.LoadNaturalTestSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNaturalTestSetButtonPushed, true);
            app.LoadNaturalTestSetButton.Text = 'Load Natural Test Set';
            app.PredictImageButton = uibutton(app.TrainModel, 'push');
            app.PredictImageButton.Text = 'Predict Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = train_model

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrainModel)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainModel)
        end
    end
end